classdef SatUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        KmLabel                     matlab.ui.control.Label
        HeightSpinner               matlab.ui.control.Spinner
        HeightSpinnerLabel          matlab.ui.control.Label
        DegLabel_2                  matlab.ui.control.Label
        DegLabel                    matlab.ui.control.Label
        LatitudeSpinner             matlab.ui.control.Spinner
        LatitudeSpinnerLabel        matlab.ui.control.Label
        LongitudeSpinner            matlab.ui.control.Spinner
        LongitudeSpinnerLabel       matlab.ui.control.Label
        SatelliteSystemButtonGroup  matlab.ui.container.ButtonGroup
        BDSCheckBox                 matlab.ui.control.CheckBox
        QZSSCheckBox                matlab.ui.control.CheckBox
        GPSCheckBox                 matlab.ui.control.CheckBox
        EpochEditField              matlab.ui.control.NumericEditField
        EpochEditFieldLabel         matlab.ui.control.Label
        EpochProgressSlider         matlab.ui.control.Slider
        EpochProgressSliderLabel    matlab.ui.control.Label
        GroundTrackPanel            matlab.ui.container.Panel
        SkyPlotPanel                matlab.ui.container.Panel
        StartButton                 matlab.ui.control.Button
    end

    
    properties (Access = private)
        GPS
        QZSS
        BDS
        Ground
        is_gps_true
        is_qzss_true
        is_bds_true
    end
    
    methods (Access = private)
        
        function Setground(app)
            app.GPS = app.GPS.Set_enu(app.Ground);
            app.QZSS = app.QZSS.Set_enu(app.Ground);
            app.BDS = app.BDS.Set_enu(app.Ground);
        end
        
        function Skyplotting(app)
            %cla(app.SkyPlotPanel)
            %hold(app.SkyPlotPanel,'on')
            %skyplot(app.SkyPlotPanel, NaN, NaN)
            if app.is_gps_true
                az = azimuth(app.GPS.enudataset);
                az(az<0) = az(az<0) + 360;
                el = elevation(app.GPS.enudataset, 10);
                skyplot(app.SkyPlotPanel,az,el);
            end

            if app.is_qzss_true
                az = azimuth(app.QZSS.enudataset);
                az(az<0) = az(az<0) + 360;
                el = elevation(app.QZSS.enudataset, 10);
                skyplot(app.SkyPlotPanel,az,el);
            end
            
            if app.is_bds_true
                az = azimuth(app.BDS.enudataset);
                az(az<0) = az(az<0) + 360;
                el = elevation(app.BDS.enudataset, 10);
                skyplot(app.SkyPlotPanel,az,el);
            end

            if ~(app.is_gps_true | app.is_qzss_true | app.is_bds_true)
                skyplot(app.SkyPlotPanel, NaN, NaN)
            end

        end
        
        function Geoplotting(app)
            gx = geoaxes(app.GroundTrackPanel);
            if app.is_gps_true
                geoplot(gx, app.GPS.geodataset(:,1), app.GPS.geodataset(:,2), 'bo');
            end

            if app.is_qzss_true
                geoplot(gx, app.QZSS.geodataset(:,1), app.QZSS.geodataset(:,2), 'go');
            end

            if app.is_bds_true
                geoplot(gx, app.BDS.geodataset(:,1), app.BDS.geodataset(:,2), 'ro');
            end

            if ~(app.is_gps_true | app.is_qzss_true | app.is_bds_true)
                geoplot(gx, 0, 0)
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            load("nav.mat");
            timestep = 600;
            app.GPS = Satellite(nav.GPS, timestep);
            app.QZSS = Satellite(nav.QZSS, timestep);
            app.BDS = Satellite(nav.BDS, timestep);
            app.Ground = [45,128.128,1];
            app.is_gps_true = 0;
            app.is_qzss_true = 0;
            app.is_bds_true = 0;
            Setground(app)
            Skyplotting(app)
            Geoplotting(app)
        end

        % Callback function: HeightSpinner, HeightSpinner, LatitudeSpinner,
        % 
        % ...and 3 other components
        function SpinnerValueChanged(app, event)
            app.Ground(1) = app.LatitudeSpinner.Value;
            app.Ground(2) = app.LongitudeSpinner.Value;
            app.Ground(3) = app.HeightSpinner.Value;
            app.Setground()
            app.Skyplotting()
        end

        % Value changed function: GPSCheckBox
        function GPSCheckBoxValueChanged(app, event)
            app.is_gps_true = app.GPSCheckBox.Value;
            app.Skyplotting()
            app.Geoplotting()
        end

        % Value changed function: BDSCheckBox
        function BDSCheckBoxValueChanged(app, event)
            app.is_bds_true = app.BDSCheckBox.Value;
            app.Skyplotting()
            app.Geoplotting()
        end

        % Value changed function: QZSSCheckBox
        function QZSSCheckBoxValueChanged(app, event)
            app.is_qzss_true = app.QZSSCheckBox.Value;
            app.Skyplotting()
            app.Geoplotting()
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.Text = 'Start';
            app.SkyPlotPanel = uipanel(app.UIFigure);
            app.SkyPlotPanel.Title = 'Sky Plot';
            app.GroundTrackPanel = uipanel(app.UIFigure);
            app.GroundTrackPanel.Title = 'Ground Track';
            app.EpochProgressSliderLabel = uilabel(app.UIFigure);
            app.EpochProgressSliderLabel.Text = 'Epoch Progress';
            app.EpochProgressSlider = uislider(app.UIFigure);
            app.EpochProgressSlider.MajorTicks = [];
            app.EpochProgressSlider.MajorTickLabels = {''};
            app.EpochProgressSlider.MinorTicks = [];
            app.EpochEditFieldLabel = uilabel(app.UIFigure);
            app.EpochEditFieldLabel.Text = 'Epoch';
            app.EpochEditField = uieditfield(app.UIFigure, 'numeric');
            app.SatelliteSystemButtonGroup = uibuttongroup(app.UIFigure);
            app.SatelliteSystemButtonGroup.Title = 'Satellite System';
            app.GPSCheckBox = uicheckbox(app.SatelliteSystemButtonGroup);
            app.GPSCheckBox.ValueChangedFcn = createCallbackFcn(app, @GPSCheckBoxValueChanged, true);
            app.GPSCheckBox.Text = 'GPS';
            app.QZSSCheckBox = uicheckbox(app.SatelliteSystemButtonGroup);
            app.QZSSCheckBox.ValueChangedFcn = createCallbackFcn(app, @QZSSCheckBoxValueChanged, true);
            app.QZSSCheckBox.Text = 'QZSS';
            app.BDSCheckBox = uicheckbox(app.SatelliteSystemButtonGroup);
            app.BDSCheckBox.ValueChangedFcn = createCallbackFcn(app, @BDSCheckBoxValueChanged, true);
            app.BDSCheckBox.Text = 'BDS';
            app.LongitudeSpinnerLabel = uilabel(app.UIFigure);
            app.LongitudeSpinnerLabel.Text = 'Longitude';
            app.LongitudeSpinner = uispinner(app.UIFigure);
            app.LongitudeSpinner.ValueChangingFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.LongitudeSpinner.Limits = [0 360];
            app.LongitudeSpinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.LongitudeSpinner.Value = 128.128;
            app.LatitudeSpinnerLabel = uilabel(app.UIFigure);
            app.LatitudeSpinnerLabel.Text = 'Latitude';
            app.LatitudeSpinner = uispinner(app.UIFigure);
            app.LatitudeSpinner.ValueChangingFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.LatitudeSpinner.Limits = [-90 90];
            app.LatitudeSpinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.LatitudeSpinner.Value = 45;
            app.DegLabel = uilabel(app.UIFigure);
            app.DegLabel.Text = 'Deg';
            app.DegLabel_2 = uilabel(app.UIFigure);
            app.DegLabel_2.Text = 'Deg';
            app.HeightSpinnerLabel = uilabel(app.UIFigure);
            app.HeightSpinnerLabel.Text = 'Height';
            app.HeightSpinner = uispinner(app.UIFigure);
            app.HeightSpinner.ValueChangingFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.HeightSpinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.HeightSpinner.Value = 1;
            app.KmLabel = uilabel(app.UIFigure);
            app.KmLabel.Text = 'Km';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SatUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end